classdef GUI_interactive < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        GridLayout           matlab.ui.container.GridLayout
        CloseButton          matlab.ui.control.Button
        foundthepieceButton  matlab.ui.control.Button
        PlayButton           matlab.ui.control.Button
        UIAxes               matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        cam
        num_of_piece
        num_row
        num_col
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, num_of_piece, num_row, num_col)
            app.num_of_piece = num_of_piece;
            app.num_row = num_row;
            app.num_col = num_col;
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
%             cam = webcam(1); % camera on
%             preview(cam);
            app.cam = webcam(1);
            frame = snapshot(app.cam);
            im = image(app.UIAxes, zeros(size(frame),'uint8'));
            axis(app.UIAxes,'image');

            preview(app.cam,im);
        end

        % Button pushed function: foundthepieceButton
        function foundthepieceButtonPushed(app, event)
            solve_puzzle(app.num_of_piece,app.num_row,app.num_col,app.cam);
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            close all force;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Title')
            zlabel(app.UIAxes, 'Z')
            app.PlayButton = uibutton(app.GridLayout, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.foundthepieceButton = uibutton(app.GridLayout, 'push');
            app.foundthepieceButton.ButtonPushedFcn = createCallbackFcn(app, @foundthepieceButtonPushed, true);
            app.foundthepieceButton.Text = 'found the piece!';
            app.CloseButton = uibutton(app.GridLayout, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_interactive(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end